classdef filtersCharacterization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        filtersCharacterizationUI  matlab.ui.Figure
        uiAxesFilter4              matlab.ui.control.UIAxes
        uiAxesFilter3              matlab.ui.control.UIAxes
        uiAxesFilter2              matlab.ui.control.UIAxes
        uiAxesFilter1              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, allFilterValues, fs)
            
            %% Filter 1
            filterVals = allFilterValues(1,:);
            plotAxis = app.uiAxesFilter1;
            titleStr = {strcat(num2str(filterVals(1)), "-", num2str(filterVals(2)), " Hz"); strcat("Order: ", num2str(filterVals(3)))};
            b = fir1(filterVals(3), [filterVals(1)/(fs/2) filterVals(2)/(fs/2)], 'bandpass');
            [h,f] = freqz(b,1,fs,fs);
            plot(plotAxis, f, 20*log10(abs(h)), '-k', 'LineWidth', 0.5)
            title(plotAxis, titleStr)
            xlabel(plotAxis, 'Frequency (Hz)');
            ylabel(plotAxis, {"Amplitude Attenuation"; "(dB)"});
            xlim(plotAxis, [0 fs/2]);
            grid(plotAxis, "minor");

            %% Filter 2
            filterVals = allFilterValues(2,:);
            plotAxis = app.uiAxesFilter2;
            titleStr = {strcat(num2str(filterVals(1)), "-", num2str(filterVals(2)), " Hz"); strcat("Order: ", num2str(filterVals(3)))};
            b = fir1(filterVals(3), [filterVals(1)/(fs/2) filterVals(2)/(fs/2)], 'bandpass');
            [h,f] = freqz(b,1,fs,fs);
            plot(plotAxis, f, 20*log10(abs(h)), '-k', 'LineWidth', 0.5);
            title(plotAxis, titleStr)
            xlabel(plotAxis, 'Frequency (Hz)');
            ylabel(plotAxis, {"Amplitude Attenuation"; "(dB)"});
            xlim(plotAxis, [0 fs/2]);
            grid(plotAxis, "minor");


            %% Filter 3
            filterVals = allFilterValues(3,:);
            plotAxis = app.uiAxesFilter3;
            titleStr = {strcat(num2str(filterVals(1)), "-", num2str(filterVals(2)), " Hz"); strcat("Order: ", num2str(filterVals(3)))};
            b = fir1(filterVals(3), [filterVals(1)/(fs/2) filterVals(2)/(fs/2)], 'bandpass');
            [h,f] = freqz(b,1,fs,fs);
            plot(plotAxis, f, 20*log10(abs(h)), '-k', 'LineWidth', 0.5);
            title(plotAxis, titleStr)
            xlabel(plotAxis, 'Frequency (Hz)');
            ylabel(plotAxis, {"Amplitude Attenuation"; "(dB)"});
            xlim(plotAxis, [0 fs/2]);
            grid(plotAxis, "minor");


            %% Filter 4
            filterVals = allFilterValues(4,:);
            plotAxis = app.uiAxesFilter4;
            titleStr = {strcat(num2str(filterVals(1)), "-", num2str(filterVals(2)), " Hz"); strcat("Order: ", num2str(filterVals(3)))};
            b = fir1(filterVals(3), [filterVals(1)/(fs/2) filterVals(2)/(fs/2)], 'bandpass');
            [h,f] = freqz(b,1,fs,fs);
            plot(plotAxis, f, 20*log10(abs(h)), '-k', 'LineWidth', 0.5);
            title(plotAxis, titleStr)
            xlabel(plotAxis, 'Frequency (Hz)');
            ylabel(plotAxis, {"Amplitude Attenuation"; "(dB)"});
            xlim(plotAxis, [0 fs/2]);
            grid(plotAxis, "minor");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.filtersCharacterizationUI = uifigure('Visible', 'off');
            app.filtersCharacterizationUI.Name = 'Filters Characterization';
            app.filtersCharacterizationUI.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.uiAxesFilter1 = uiaxes(app.filtersCharacterizationUI);
            title(app.uiAxesFilter1, 'Title')
            xlabel(app.uiAxesFilter1, 'X')
            ylabel(app.uiAxesFilter1, 'Y')
            zlabel(app.uiAxesFilter1, 'Z')
            app.uiAxesFilter2 = uiaxes(app.filtersCharacterizationUI);
            title(app.uiAxesFilter2, 'Title')
            xlabel(app.uiAxesFilter2, 'X')
            ylabel(app.uiAxesFilter2, 'Y')
            zlabel(app.uiAxesFilter2, 'Z')
            app.uiAxesFilter3 = uiaxes(app.filtersCharacterizationUI);
            title(app.uiAxesFilter3, 'Title')
            xlabel(app.uiAxesFilter3, 'X')
            ylabel(app.uiAxesFilter3, 'Y')
            zlabel(app.uiAxesFilter3, 'Z')
            app.uiAxesFilter4 = uiaxes(app.filtersCharacterizationUI);
            title(app.uiAxesFilter4, 'Title')
            xlabel(app.uiAxesFilter4, 'X')
            ylabel(app.uiAxesFilter4, 'Y')
            zlabel(app.uiAxesFilter4, 'Z')
            % Show the figure after all components are created
            app.filtersCharacterizationUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filtersCharacterization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.filtersCharacterizationUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.filtersCharacterizationUI)
        end
    end
end