classdef noiseMapApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NoiseMapFigure  matlab.ui.Figure
        chSpecNI_Axes   matlab.ui.control.UIAxes
        NoiseMapAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        timeVec = []; % Description
        callerHdl = [];
        crossAvgHdl = [];
        crossSpecHdl = [];
    end
    
    methods (Access = private)
        function getClickInMap_Callback(app, source, eventdata)
            mousePos = get(0, 'PointerLocation');
            wWdwPercentage = (mousePos(1)/app.NoiseMapAxes.Position(3));
            idx = round(wWdwPercentage *length(app.timeVec));
            idx = floor(eventdata.IntersectionPoint(1))+1;
            if idx <1
                idx = 1;
            end
            clickTime = app.timeVec(idx);%aap.timeVec(idx);
            app.callerHdl.noiseMapClickSynch(clickTime)
        end

    end
    
    methods (Access = public)
        
        function getNewCrossPosition(app, crosshairsPos)
            app.crossAvgHdl{1}.XData = [crosshairsPos(1), crosshairsPos(1)];
            app.crossAvgHdl{2}.XData = [crosshairsPos(2), crosshairsPos(2)];

            app.crossSpecHdl{1}.XData = [crosshairsPos(1), crosshairsPos(1)];
            app.crossSpecHdl{2}.XData = [crosshairsPos(2), crosshairsPos(2)];
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, crosshairsPos, timeVec, chspec_ni_vec, chavg_ni_vec, mtgLabels)

            nrMtgs = size(chspec_ni_vec,1);
            app.timeVec = timeVec;
            app.callerHdl  = caller;
            
            % Channel Average NI
            clims = [0 1];
            chAvgNoiseMapPlot = imagesc(app.NoiseMapAxes, chavg_ni_vec, clims);
            colormap(app.NoiseMapAxes, flipud(gray));
            colorbar(app.NoiseMapAxes);
            xlim(app.NoiseMapAxes, [1 length(timeVec)]);
            xticks(app.NoiseMapAxes, timeVec);
            yticks(app.NoiseMapAxes, 1);
            ylabel(app.NoiseMapAxes, '');
            xlabel(app.NoiseMapAxes, '');
            xticklabels(app.NoiseMapAxes, []);
            yticklabels(app.NoiseMapAxes, 'Avg.NI');
            title(app.NoiseMapAxes, {'Avg. Spectrum'; 'NI'})
            hold(app.NoiseMapAxes, 'on');
            h = colorbar(app.NoiseMapAxes);
            h.Label.String = "Noise Index(0-1)";
            h.Label.Rotation = 270;
            h.Label.VerticalAlignment = "bottom";
            % Crosshairs
            cA1 = plot(app.NoiseMapAxes, [crosshairsPos(1), crosshairsPos(1)], [0.5 1.5], '-', 'Color', [0 1 0 0.2], 'LineWidth', 5);
            cA2 = plot(app.NoiseMapAxes, [crosshairsPos(2), crosshairsPos(2)], [0.5 1.5], '-', 'Color', [0 1 0 0.2], 'LineWidth', 5);
            app.crossAvgHdl = {cA1, cA2};

            %% Channel Specific NI
            clims = [0 1];
            chSpecNoiseMapPlot = imagesc(app.chSpecNI_Axes, chspec_ni_vec, clims);
            colormap(app.chSpecNI_Axes, flipud(gray));
            xticks(app.chSpecNI_Axes, 1:length(timeVec));
            yticks(app.chSpecNI_Axes, 1:nrMtgs);
            xlim(app.chSpecNI_Axes, [1 length(timeVec)]);
            %ylim(app.chSpecNI_Axes, [1 nrMtgs+1]);
            xticklabels(app.chSpecNI_Axes, '');
            yticklabels(app.chSpecNI_Axes, mtgLabels);
            ylabel(app.chSpecNI_Axes, '');
            xlabel(app.chSpecNI_Axes, 'Time (s)');    
            title(app.chSpecNI_Axes, {'Channel Specific Spectrum'; 'NI'})
            hold(app.chSpecNI_Axes, 'on');
            % Colorbar
            h = colorbar(app.chSpecNI_Axes);
            h.Label.String = "Noise Index(0-1)";
            h.Label.Rotation = 270;
            h.Label.VerticalAlignment = "bottom";
            % Grid
            gridX = repmat([1 length(timeVec)], nrMtgs+1,1)';
            gridY = [0.5:1:nrMtgs+0.5; 0.5:1:nrMtgs+0.5]; 
            plot(app.chSpecNI_Axes, gridX, gridY, '-w');
            % Crosshairs
            cB1 = plot(app.chSpecNI_Axes, [crosshairsPos(1), crosshairsPos(1)], [0.5,18.5], '-', 'Color', [0 1 0 0.2], 'LineWidth', 5);
            cB2 = plot(app.chSpecNI_Axes, [crosshairsPos(2), crosshairsPos(2)], [0.5,18.5], '-', 'Color', [0 1 0 0.2], 'LineWidth', 5);
            app.crossSpecHdl = {cB1, cB2};

            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);    
            screenHeight = screenSize(4);
            
            app.NoiseMapFigure.Position = [0 0 screenWidth screenHeight];

            % Avg NI position
            left = screenWidth * 0.02;
            width = screenWidth * 0.92;
            bottom = screenHeight*0.8;
            height = screenHeight*0.2;
            app.NoiseMapAxes.Position = [left bottom width height];

            % Channel Specific NI position
            left = screenWidth * 0.02;
            width = screenWidth * 0.92;
            bottom = screenHeight*0.05;
            height = screenHeight*0.7;
            app.chSpecNI_Axes.Position = [left bottom width height];

            set(chAvgNoiseMapPlot,'ButtonDownFcn',{@app.getClickInMap_Callback});
            set(chSpecNoiseMapPlot,'ButtonDownFcn',{@app.getClickInMap_Callback});

        end

        % Close request function: NoiseMapFigure
        function NoiseMapFigureCloseRequest(app, event)
            delete(app)
        end

        % Window button down function: NoiseMapFigure
        function NoiseMapFigureWindowButtonDown(app, event)
            % mousePos = get(0, 'PointerLocation');
            % wWdwPercentage = (mousePos(1)/app.NoiseMapAxes.Position(3));
            % idx = round(wWdwPercentage *length(app.timeVec));
            % clickTime = app.timeVec(idx);
            % app.callerHdl.noiseMapClickSynch(clickTime)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NoiseMapFigure = uifigure('Visible', 'off');
            app.NoiseMapFigure.Name = 'MATLAB App';
            app.NoiseMapFigure.CloseRequestFcn = createCallbackFcn(app, @NoiseMapFigureCloseRequest, true);
            app.NoiseMapFigure.WindowButtonDownFcn = createCallbackFcn(app, @NoiseMapFigureWindowButtonDown, true);
            app.NoiseMapAxes = uiaxes(app.NoiseMapFigure);
            title(app.NoiseMapAxes, 'Noise Map')
            xlabel(app.NoiseMapAxes, 'X')
            ylabel(app.NoiseMapAxes, 'Y')
            zlabel(app.NoiseMapAxes, 'Z')
            app.chSpecNI_Axes = uiaxes(app.NoiseMapFigure);
            title(app.chSpecNI_Axes, 'Title')
            xlabel(app.chSpecNI_Axes, 'X')
            ylabel(app.chSpecNI_Axes, 'Y')
            zlabel(app.chSpecNI_Axes, 'Z')
            % Show the figure after all components are created
            app.NoiseMapFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noiseMapApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NoiseMapFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NoiseMapFigure)
        end
    end
end